classdef EBSDpolygonizer_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RightPanel                matlab.ui.container.Panel
        EBSDPolygonizerLabel      matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        ImportTab                 matlab.ui.container.Tab
        ImportButton              matlab.ui.control.Button
        SelectpixeldataButton     matlab.ui.control.Button
        PixelDataFilePathBox      matlab.ui.control.TextArea
        SelectgraindataButton     matlab.ui.control.Button
        GrainDataFilePathBox      matlab.ui.control.TextArea
        ImportLog                 matlab.ui.control.TextArea
        FiguresTab                matlab.ui.container.Tab
        OriginalPanel             matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ProcessedPanel            matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        FileMenu                  matlab.ui.container.Menu
        ImportHKLChannelDataMenu  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        ProcessMenu               matlab.ui.container.Menu
        SimplifyMenu              matlab.ui.container.Menu
        SmoothMenu                matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        PlotMenu                  matlab.ui.container.Menu
        MapMenu                   matlab.ui.container.Menu
        GrainsMenu                matlab.ui.container.Menu
        GrainboundariesMenu       matlab.ui.container.Menu
        VerticesMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        grains % Description
        pixels % EBSD pixel ebsd.pixcell
        pixelDataFilePath
        grainDataFilePath
        stepsize
        map
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ImportButton, ImportHKLChannelDataMenu
        function ImportButtonPushed(app, event)
            app.ImportLog.Value = '';
            if isempty(app.pixelDataFilePath)
                app.SelectpixeldataButtonPushed;
            end
            datafile = app.pixelDataFilePath;
            fileID = fopen(datafile,'r');
            delimiter = '\t';
            startRow = 2;
            endRow = inf;
            formatSpec = '%f%s%f%f%f%f%f%f%f%f%f%f%[^\n\r]';
            dataArray = textscan(fileID, formatSpec, endRow-startRow+1, 'Delimiter', delimiter, 'TextType', 'string', 'EmptyValue', NaN, 'HeaderLines', startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            app.pixels = ebsd.pixcell;
            app.pixels.index = dataArray{:,1};
            app.pixels.phase = dataArray{:,2};
            app.pixels.x = dataArray{:,3};
            app.pixels.y = dataArray{:,4};
            app.pixels.phi1 = dataArray{:,5};
            app.pixels.PHI = dataArray{:,6};
            app.pixels.phi2 = dataArray{:,7};
            app.pixels.mad = dataArray{:,8};
            app.pixels.bandcontr = dataArray{:,10};
            app.pixels.bs = dataArray{:,11};
            app.pixels.grainID = dataArray{:,12};
                ux = unique(app.pixels.x);
                uy = unique(app.pixels.y);
                uxdif = diff(ux);
                uydif = diff(uy);
                xstep = min(unique(uxdif));
                ystep = min(unique(uydif));
                if xstep == ystep && xstep ~= 0
                    app.pixels.stepsize = xstep;
                else
                    % If the step size cannot be determined from the data,
                    % The user is asked to input it (in micron)
                    % If no input is returned, step size is set to 10.
                    stepanswer = inputdlg('I cannot work out the step size, please input here: [unit: micron]', 'Input step size', 1, {'1'});
                    app.pixels.stepsize = str2double(stepanswer{1});
                end
            fclose(fileID);
            textlog = [num2str(length(app.pixels.x)), ' pixels imported.'];
            app.ImportLog.Value = textlog;
            
            if isempty(app.grainDataFilePath)
                app.SelectgraindataButtonPushed;
            end
            
            grfileID = fopen(app.grainDataFilePath,'r');
            delimiter = '\t';
            startRow = 2;
            endRow = inf;
            formatSpec = '%f%s%f%f%f%f%f%f%f%f%f%f%f%f%f%s%s%[^\n\r]';
            dataArray = textscan(grfileID, formatSpec, endRow-startRow+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            
            eg(size(dataArray{1},1),1) = ebsd.grain;
            for i = 1:length(eg)
                eg(i) = ebsd.grain;
                eg(i).ID = dataArray{1}(i);
                eg(i).phase = dataArray{2}(i);
                eg(i).diam = dataArray{4}(i);
                eg(i).area = dataArray{3}(i);
                eg(i).aspectratio = dataArray{9}(i);
                eg(i).xcg = dataArray{6}(i);
                eg(i).ycg = dataArray{7}(i);
                eg(i).meanphi1 = dataArray{13}(i);
                eg(i).meanPHI = dataArray{14}(i);
                eg(i).meanphi2 = dataArray{15}(i);
                border = dataArray{17}(i);
                switch border
                    case "no"
                        eg(i).isedge = false;
                        eg(i).iscorner = false;
                    case "edge"
                        eg(i).isedge = true;
                        eg(i).iscorner = false;
                    case "corner"
                        eg(i).isedge = true;
                        eg(i).iscorner = true;
                end
            end
            app.grains = eg;
            textlog = append(textlog, [newline, '* Grain data imported and ',num2str(length(eg)),' grains created.']);
            app.ImportLog.Value = textlog;
            app.grains.claimownership(app.pixels);
            textlog = append(textlog, [newline, 'Pixels claimed by their owner grain.']);
            app.ImportLog.Value = textlog;
            textlog = append(textlog, [newline, 'I am now polygonizing the grains. It may take a while, please be patient...']);
            app.grains.polygonize;
            app.ImportLog.Value = textlog;
            textlog = append(textlog, [newline, '*Done!', newline, '********************']);
            app.ImportLog.Value = textlog;
            % Map
            app.map = ebsd.map(app.grains);
            textlog = append(textlog, [newline, 'EBSD map created.']);
            app.ImportLog.Value = textlog;
            % Find neighbours
            app.grains.findneighbours(0.1*app.pixels.stepsize);
            textlog = append(textlog, [newline, 'Grain neighbourhood checked.']);
            app.ImportLog.Value = textlog;
            % GB
            app.map.findgbs;
            textlog = append(textlog, [newline, '* Grain boundaries created.', newline, '** I am about to finish and doing some finishing touches.']);
            app.ImportLog.Value = textlog;
            % 
            app.map.addMissingVertices(app.pixels.stepsize*0.2);
            app.map.findEdgeVertices;
            % 
            textlog = append(textlog, [newline, '====================================', newline, 'All done!', newline, 'You can now view and process the map!']);
            app.ImportLog.Value = textlog;
            app.MapMenuSelected;
        end

        % Button pushed function: SelectpixeldataButton
        function SelectpixeldataButtonPushed(app, event)
            [dotfilename,dotfilepath] = uigetfile('*.txt','Select the EBSD data file in the format of text file exported by HKL Channel 5');
            app.pixelDataFilePath= [dotfilepath, dotfilename];
            app.PixelDataFilePathBox.Value = app.pixelDataFilePath;
        end

        % Button pushed function: SelectgraindataButton
        function SelectgraindataButtonPushed(app, event)
            [grfilename,grfilepath] = uigetfile('*.txt','Select the EBSD grain list file in the format of text file exported by HKL Channel 5');
            app.grainDataFilePath = [grfilepath, grfilename];
            app.GrainDataFilePathBox.Value = app.grainDataFilePath;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            map = app.map;
            uisave('app.map')

        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [filename,filepath] = uigetfile('*.mat', "Select MAT file to load the ebsd.");
            filetoload = [filepath,filename];
            s=load(filetoload);
            app.map = s.map;
            app.MapMenuSelected;
            
        end

        % Menu selected function: MapMenu
        function MapMenuSelected(app, event)
            plot(app.UIAxes,vertcat(app.map.grains.polygon));
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, '\mum');
            ylabel(app.UIAxes,'\mum');
            app.UIAxes.Box = 'off';
            app.UIAxes.XLim = [app.map.leftedge, app.map.rightedge];
            app.UIAxes.YLim = [app.map.bottomedge, app.map.topedge];
            hold(app.UIAxes, 'on')
            daspect(app.UIAxes,[1 1 1]);
            pbaspect(app.UIAxes, [1 1 1]);
            hold(app.UIAxes, 'off');
        end

        % Menu selected function: GrainboundariesMenu
        function GrainboundariesMenuSelected(app, event)
            hgb = app.map.plotgb; 
        end

        % Menu selected function: VerticesMenu
        function VerticesMenuSelected(app, event)
            hvcs = app.map.plotvertices;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {515, 515};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {314, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.EBSDPolygonizerLabel = uilabel(app.RightPanel);
            app.EBSDPolygonizerLabel.Text = 'EBSD Polygonizer';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.ImportButton = uibutton(app.ImportTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SelectpixeldataButton = uibutton(app.ImportTab, 'push');
            app.SelectpixeldataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpixeldataButtonPushed, true);
            app.SelectpixeldataButton.Text = 'Select pixel data';
            app.PixelDataFilePathBox = uitextarea(app.ImportTab);
            app.SelectgraindataButton = uibutton(app.ImportTab, 'push');
            app.SelectgraindataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgraindataButtonPushed, true);
            app.SelectgraindataButton.Text = 'Select grain data';
            app.GrainDataFilePathBox = uitextarea(app.ImportTab);
            app.ImportLog = uitextarea(app.ImportTab);
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.OriginalPanel = uipanel(app.FiguresTab);
            app.OriginalPanel.Title = 'Original';
            app.UIAxes = uiaxes(app.OriginalPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ProcessedPanel = uipanel(app.FiguresTab);
            app.ProcessedPanel.Title = 'Processed';
            app.UIAxes2 = uiaxes(app.ProcessedPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportHKLChannelDataMenu = uimenu(app.FileMenu);
            app.ImportHKLChannelDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportHKLChannelDataMenu.Text = 'Import HKL Channel Data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.SimplifyMenu = uimenu(app.ProcessMenu);
            app.SimplifyMenu.Text = 'Simplify';
            app.SmoothMenu = uimenu(app.ProcessMenu);
            app.SmoothMenu.Text = 'Smooth';
            app.Menu2 = uimenu(app.ProcessMenu);
            app.Menu2.Text = 'Menu2';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.MapMenu = uimenu(app.PlotMenu);
            app.MapMenu.MenuSelectedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapMenu.Text = 'Map';
            app.GrainsMenu = uimenu(app.PlotMenu);
            app.GrainsMenu.Text = 'Grains';
            app.GrainboundariesMenu = uimenu(app.PlotMenu);
            app.GrainboundariesMenu.MenuSelectedFcn = createCallbackFcn(app, @GrainboundariesMenuSelected, true);
            app.GrainboundariesMenu.Text = 'Grain boundaries';
            app.VerticesMenu = uimenu(app.PlotMenu);
            app.VerticesMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticesMenuSelected, true);
            app.VerticesMenu.Text = 'Vertices';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBSDpolygonizer_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end