classdef EBSDpolygonizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        ImportHKLChannelDataMenu  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        ProcessMenu               matlab.ui.container.Menu
        SimplifyMenu              matlab.ui.container.Menu
        SmoothMenu                matlab.ui.container.Menu
        DownsizeMenu              matlab.ui.container.Menu
        PlotMenu                  matlab.ui.container.Menu
        MapMenu                   matlab.ui.container.Menu
        GrainsMenu                matlab.ui.container.Menu
        GrainboundariesMenu       matlab.ui.container.Menu
        VerticesMenu              matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        TutorialMenu              matlab.ui.container.Menu
        DocumentationMenu         matlab.ui.container.Menu
        FilePanel                 matlab.ui.container.Panel
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ImportButton              matlab.ui.control.Button
        ProcessPanel              matlab.ui.container.Panel
        SimplifyButton            matlab.ui.control.Button
        PassesSpinnerLabel        matlab.ui.control.Label
        PassesSpinner             matlab.ui.control.Spinner
        TolLabel                  matlab.ui.control.Label
        TolEditField              matlab.ui.control.NumericEditField
        SmoothButton              matlab.ui.control.Button
        DownsizeButton            matlab.ui.control.Button
        SpanLabel                 matlab.ui.control.Label
        SpanSpinner               matlab.ui.control.Spinner
        TolIncrmEditFieldLabel    matlab.ui.control.Label
        TolIncrmEditField         matlab.ui.control.NumericEditField
        ResetmapButton            matlab.ui.control.Button
        PlotPanel                 matlab.ui.container.Panel
        MapButton                 matlab.ui.control.Button
        GBsButton                 matlab.ui.control.Button
        VerticesButton            matlab.ui.control.Button
        GrainlabelsSwitchLabel    matlab.ui.control.Label
        GrainlabelsSwitch         matlab.ui.control.Switch
        NeighboursButton          matlab.ui.control.Button
        ImportLog                 matlab.ui.control.TextArea
        ClearButton               matlab.ui.control.Button
        UIAxes3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        grains % Description
        pixels % EBSD pixel ebsd.pixcell
        pixelDataFilePath
        grainDataFilePath
        stepsize
        map
        textlog = ''
        virginMap % Description
        mapfilePath % Description
        tempmapfile % Description
        lastfolderPath
        projectDataFilePath % Description
    end
    
    methods 
        function value = get.textlog(app)
            if isempty(app.textlog)
                value = app.ImportLog.Value;
            else
                value = app.textlog;
            end
        end
        
        function value = get.lastfolderPath(app)
            if isempty(app.lastfolderPath)
                value = pwd;
            else
                value = app.lastfolderPath;
            end
        end
    end
    
    methods (Access = private)
        function printToLogWindow(app, texts)
            app.textlog = vertcat({texts}, app.textlog);
            
            
            app.ImportLog.Value = app.textlog;
        end
        
        function simplify(app,smoothspan, tol,numpasses,tolincr)
            if nargin == 2
                numpasses = 5;
                tolincr = 10;
            elseif nargin == 3
                tolincr = 10;
            end
            n0 = app.map.Nvertices;
            app.printToLogWindow(['Number of vertices: ', num2str(n0)]);
            for i = 1:numpasses
                ns = app.map.Nvertices;
                app.map.smoothgb({smoothspan});
                app.map.downsize(tol);
                nf = app.map.Nvertices;
                frac = 100*(nf-ns)/n0;
                app.printToLogWindow(['Number of vertices: ', num2str(nf), '; reduced by ', num2str(frac), '%']); 
                tol = tol+tolincr;
            end
        end


        function plotmap(app, ax)
            if nargin < 2
                ax = gca;
            end
            plot(ax,vertcat(app.map.grains.polygon));
            title(ax, 'Map')
            xlabel(ax, '\mum');
            ylabel(ax,'\mum');
            ax.Box = 'off';
            ax.XLim = [app.map.leftedge, app.map.rightedge];
            ax.YLim = [app.map.bottomedge, app.map.topedge];
            hold(ax, 'on')
            daspect(ax,[1 1 1]);
            pbaspect(ax, [1 1 1]);
            hold(ax, 'off');
        end
        
        function loadmap(app, filetoload, mapname)
            if nargin < 3
                mapname = 'emap';
            end
            s=load(filetoload);
            app.map = s.(mapname);
%             s = load(filetoload);
%             app.virginMap = app.map;
            app.UIAxes3.Visible = 'on';
            app.plotmap(app.UIAxes3);
            app.printToLogWindow(['>>>>>>>>>>','EBSD.MAP loaded from ', filetoload, '<<<<<<<<<<<']);
        end
        
        
        function closerequest(app)
            delete(app.tempmapfile)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SelectpixeldataButtonPushed(app, event)
            defaultfolder = app.lastfolderPath;
            [dotfilename,dotfilepath] = uigetfile('*.txt','Select EBSD pixel data file',defaultfolder);
            if ~isempty(dotfilepath)
                app.pixelDataFilePath= [dotfilepath, dotfilename];
                app.PixelDataFilePathBox.Value = app.pixelDataFilePath;
                app.lastfolderPath = dotfilepath;
            end
        end

        % Callback function
        function SelectgraindataButtonPushed(app, event)
            
            [grfilename,grfilepath] = uigetfile('*.txt','Select the EBSD grain data file', app.lastfolderPath);
            if isempty(grfilepath)
                app.grainDataFilePath = [grfilepath, grfilename];
                app.GrainDataFilePathBox.Value = app.grainDataFilePath;
                app.lastfolderPath = grfilepath;
            end
        end

        % Callback function: ImportButton, ImportHKLChannelDataMenu
        function ImportButtonPushed(app, event)
%             app.ImportLog.Value = '';
            
            if isempty(app.projectDataFilePath)
                [crcfilename,crcfilepath] = uigetfile('*.cpr', 'Select HKL project file');
                app.projectDataFilePath = [crcfilepath, crcfilename];
                app.lastfolderPath = crcfilepath;
            end
            if isempty(app.pixelDataFilePath)
                [dotfilename,dotfilepath] = uigetfile('*.txt','Select the exported pixel data file',app.lastfolderPath);
                if ~isempty(dotfilepath)
                    app.pixelDataFilePath= [dotfilepath, dotfilename];
                    app.lastfolderPath = dotfilepath;
                else
                    warning('Not pixel data file given, I am exiting');
                    return
                end
            end
            ebsdpara = ebsd.map.importCRCfile(app.projectDataFilePath);
            app.stepsize = ebsdpara.xStepSize;
            datafile = app.pixelDataFilePath;
            fileID = fopen(datafile,'r');
            delimiter = '\t';
            startRow = 2;
            endRow = inf;
            formatSpec = '%f%s%f%f%f%f%f%f%f%f%f%f%[^\n\r]';
            dataArray = textscan(fileID, formatSpec, endRow-startRow+1, 'Delimiter', delimiter, 'TextType', 'string', 'EmptyValue', NaN, 'HeaderLines', startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            ed = ebsd.pixcell;
            ed.index = dataArray{:,1};
            ed.phase = dataArray{:,2};
            ed.x = dataArray{:,3};
            ed.y = dataArray{:,4};
            ed.phi1 = dataArray{:,5};
            ed.PHI = dataArray{:,6};
            ed.phi2 = dataArray{:,7};
            ed.mad = dataArray{:,8};
            ed.bandcontr = dataArray{:,10};
            ed.bs = dataArray{:,11};
            ed.grainID = dataArray{:,12};
            ed.stepsize = app.stepsize;
            ed.y = max(ed.y)+app.stepsize-ed.y;
            app.pixels = ed;
            fclose(fileID);             
            app.printToLogWindow([num2str(length(app.pixels.x)), ' pixels imported.']);
            app.ImportLog.Value = app.textlog;
            
            if isempty(app.grainDataFilePath)
                [grfilename,grfilepath] = uigetfile('*.txt','Select the EBSD grain data file',app.lastfolderPath);
                if ~isempty(grfilepath)
                    app.grainDataFilePath = [grfilepath, grfilename];
                    app.lastfolderPath = grfilepath;
                else
                    warning('No grain data file give, I am exiting.')
                    return
                end
            end
            
            grfileID = fopen(app.grainDataFilePath,'r');
            delimiter = '\t';
            startRow = 2;
            endRow = inf;
            formatSpec = '%f%s%f%f%f%f%f%f%f%f%f%f%f%f%f%s%s%[^\n\r]';
            dataArray = textscan(grfileID, formatSpec, endRow-startRow+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            
            eg(size(dataArray{1},1),1) = ebsd.grain;
            for i = 1:length(eg)
                eg(i) = ebsd.grain;
                eg(i).ID = dataArray{1}(i);
                eg(i).phase = dataArray{2}(i);
                eg(i).diam = dataArray{4}(i);
                eg(i).area = dataArray{3}(i);
                eg(i).aspectratio = dataArray{9}(i);
                eg(i).xcg = dataArray{6}(i);
                eg(i).ycg = dataArray{7}(i);
                eg(i).meanphi1 = dataArray{13}(i);
                eg(i).meanPHI = dataArray{14}(i);
                eg(i).meanphi2 = dataArray{15}(i);
                border = dataArray{17}(i);
                switch border
                    case "no"
                        eg(i).isedge = false;
                        eg(i).iscorner = false;
                    case "edge"
                        eg(i).isedge = true;
                        eg(i).iscorner = false;
                    case "corner"
                        eg(i).isedge = true;
                        eg(i).iscorner = true;
                end
            end
            app.grains = eg;
            app.printToLogWindow([newline, '* Grain data imported and ',num2str(length(eg)),' grains created.']);
            app.grains.claimownership(app.pixels);
            app.printToLogWindow([newline, 'Pixels claimed by their owner grain.']);
            app.printToLogWindow( [newline, 'I am now polygonizing the grains. It may take a while, please be patient...']);
            app.grains.polygonize(app.pixels,ebsdpara);
            app.printToLogWindow([newline, '*Polygonization done!', '********************']);
            % Map
            app.map = ebsd.map(app.grains);
            app.map.stepsize = app.stepsize;
            app.printToLogWindow([newline, 'EBSD map created.']);
            % Find neighbours
            app.grains.findneighbours(0.1*app.pixels.stepsize);
            app.printToLogWindow([newline, 'Grain neighbourhood checked.']);
            % GB
            app.map.findgbs;
            app.printToLogWindow([newline, '* Grain boundaries created.', newline, '** I am about to finish and doing some finishing touches.']);
            % 
            app.map.addMissingVertices(app.pixels.stepsize*0.2);
            app.map.findEdgeVertices;
            % 
            app.printToLogWindow([newline, '====================================', newline, 'Done!', newline, 'You can now view and process the map!']);
            app.plotmap(app.UIAxes3);
            tempmap = app.map;
            app.tempmapfile = [tempdir,'tempmapfile.mat'];
            save(app.tempmapfile, 'tempmap');
        end

        % Callback function: LoadButton, LoadMenu
        function LoadMenuSelected(app, event)
            [filename,filepath] = uigetfile('*.mat', "Select MAT file to load the ebsd.",app.lastfolderPath);
            if ~isempty(filepath)
                filetoload = [filepath,filename];
                app.mapfilePath = filetoload;
                app.lastfolderPath = filepath;
                app.loadmap(app.mapfilePath);
            end
        end

        % Callback function: SaveButton, SaveMenu
        function SaveMenuSelected(app, event)
            emap = app.map;
            uisave('emap')
        end

        % Callback function: MapButton, MapMenu
        function MapMenuSelected(app, event)
            if strcmpi(app.GrainlabelsSwitch.Value, "On")
               app.map.grains.plot; 
            else
               app.plotmap;
            end
            
        end

        % Callback function: GBsButton, GrainboundariesMenu
        function GrainboundariesMenuSelected(app, event)
            hgb = app.map.plotgb; 
        end

        % Callback function: VerticesButton, VerticesMenu
        function VerticesMenuSelected(app, event)
            hvcs = app.map.plotvertices;
        end

        % Callback function
        function ImportLogValueChanged(app, event)
            value = app.ImportLog.Value;
        end

        % Menu selected function: GrainsMenu
        function GrainsMenuSelected(app, event)
            app.map.plotWithGrainLabels;
        end

        % Button pushed function: NeighboursButton
        function NeighboursButtonPushed(app, event)
            grainid = inputdlg("Grain ID:");
            gid = str2double(grainid{1});
            app.map.plotneighbours(app.map.grains(gid));
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.map.smoothgb({app.SpanSpinner.Value});
            app.plotmap(app.UIAxes3)
        end

        % Button pushed function: DownsizeButton
        function DownsizeButtonPushed(app, event)
            app.map.downsize(app.TolEditField.Value);
            app.plotmap(app.UIAxes3);
        end

        % Button pushed function: SimplifyButton
        function SimplifyButtonPushed(app, event)
            smoothspan = app.SpanSpinner.Value;
            tol = app.TolEditField.Value;
            passes = app.PassesSpinner.Value;
            tolinc = app.TolIncrmEditField.Value;
            app.simplify(smoothspan, tol, passes, tolinc);
            app.plotmap(app.UIAxes3)
        end

        % Value changed function: ImportLog
        function ImportLogValueChanged2(app, event)
            value = app.ImportLog.Value;
            scroll(app.ImportLog, 'bottom')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ImportLog.Value = {''};
            app.textlog = {''};
        end

        % Button pushed function: ResetmapButton
        function ResetmapButtonPushed(app, event)
            if isempty(app.mapfilePath)
                app.loadmap(app.tempmapfile, 'tempmap')
            else
                app.loadmap(app.mapfilePath);
            end
        end

        % Menu selected function: SimplifyMenu
        function SimplifyMenuSelected(app, event)
            answers = inputdlg({'Enter smooth span:', 'Enter downsize tolerance:', ...
                'Enter number of passes:', 'Enter downsize tolerance increment for the next pass:'},...
                'Simplify Parameters', ...
                1, {'3', '0.1', '1', '0.1'});
            smoothspan = floor(str2double(answers{1}));
            tol = str2double(answers{2});
            numpasses = floor(str2double(answers{3}));
            tolinc = str2double(answers{4});
            app.simplify(smoothspan, tol, numpasses, tolinc);
            app.plotmap(app.UIAxes3);
        end

        % Menu selected function: SmoothMenu
        function SmoothMenuSelected(app, event)
            answers = inputdlg({'Enter smooth span:'}, 'Smooth Parameters', 1, {'3'});
            smoothspan = floor(str2double(answers));
            app.map.smoothgb({smoothspan});
            app.plotmap(app.UIAxes3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if ~isempty(app.tempmapfile)
                delete(app.tempmapfile)
            end
            delete(app)
        end

        % Menu selected function: DownsizeMenu
        function DownsizeMenuSelected(app, event)
            answers = inputdlg({'Enter downsize tolerance (if the area of the triangle for three neighbouring vertices is greater than the tolerance, the second vertices will be deleted):'}, 'Downsize Parameters', 1, {'0.1'});
            tol = str2double(answers);
            app.map.downsize(tol);
            app.plotmap(app.UIAxes3);
        end

        % Menu selected function: TutorialMenu
        function TutorialMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportHKLChannelDataMenu = uimenu(app.FileMenu);
            app.ImportHKLChannelDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportHKLChannelDataMenu.Text = 'Import HKL Channel Data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.SimplifyMenu = uimenu(app.ProcessMenu);
            app.SimplifyMenu.MenuSelectedFcn = createCallbackFcn(app, @SimplifyMenuSelected, true);
            app.SimplifyMenu.Text = 'Simplify';
            app.SmoothMenu = uimenu(app.ProcessMenu);
            app.SmoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothMenuSelected, true);
            app.SmoothMenu.Text = 'Smooth';
            app.DownsizeMenu = uimenu(app.ProcessMenu);
            app.DownsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @DownsizeMenuSelected, true);
            app.DownsizeMenu.Text = 'Downsize';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.MapMenu = uimenu(app.PlotMenu);
            app.MapMenu.MenuSelectedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapMenu.Text = 'Map';
            app.GrainsMenu = uimenu(app.PlotMenu);
            app.GrainsMenu.MenuSelectedFcn = createCallbackFcn(app, @GrainsMenuSelected, true);
            app.GrainsMenu.Text = 'Grains';
            app.GrainboundariesMenu = uimenu(app.PlotMenu);
            app.GrainboundariesMenu.MenuSelectedFcn = createCallbackFcn(app, @GrainboundariesMenuSelected, true);
            app.GrainboundariesMenu.Text = 'Grain boundaries';
            app.VerticesMenu = uimenu(app.PlotMenu);
            app.VerticesMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticesMenuSelected, true);
            app.VerticesMenu.Text = 'Vertices';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TutorialMenu = uimenu(app.HelpMenu);
            app.TutorialMenu.MenuSelectedFcn = createCallbackFcn(app, @TutorialMenuSelected, true);
            app.TutorialMenu.Text = 'Tutorial';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File';
            app.LoadButton = uibutton(app.FilePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.FilePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveButton.Text = 'Save';
            app.ImportButton = uibutton(app.FilePanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ProcessPanel = uipanel(app.UIFigure);
            app.ProcessPanel.Title = 'Process';
            app.SimplifyButton = uibutton(app.ProcessPanel, 'push');
            app.SimplifyButton.ButtonPushedFcn = createCallbackFcn(app, @SimplifyButtonPushed, true);
            app.SimplifyButton.Text = 'Simplify';
            app.PassesSpinnerLabel = uilabel(app.ProcessPanel);
            app.PassesSpinnerLabel.Text = 'Passes:';
            app.PassesSpinner = uispinner(app.ProcessPanel);
            app.PassesSpinner.Limits = [1 Inf];
            app.PassesSpinner.RoundFractionalValues = 'on';
            app.PassesSpinner.Tooltip = {'Numbe of Passes'};
            app.PassesSpinner.Value = 1;
            app.TolLabel = uilabel(app.ProcessPanel);
            app.TolLabel.Text = 'Tol :';
            app.TolEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.TolEditField.Limits = [0 Inf];
            app.SmoothButton = uibutton(app.ProcessPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.DownsizeButton = uibutton(app.ProcessPanel, 'push');
            app.DownsizeButton.ButtonPushedFcn = createCallbackFcn(app, @DownsizeButtonPushed, true);
            app.DownsizeButton.Text = 'Downsize';
            app.SpanLabel = uilabel(app.ProcessPanel);
            app.SpanLabel.Text = 'Span:';
            app.SpanSpinner = uispinner(app.ProcessPanel);
            app.SpanSpinner.Limits = [3 Inf];
            app.SpanSpinner.RoundFractionalValues = 'on';
            app.SpanSpinner.Value = 5;
            app.TolIncrmEditFieldLabel = uilabel(app.ProcessPanel);
            app.TolIncrmEditFieldLabel.Text = 'Tol. Incrm.';
            app.TolIncrmEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.ResetmapButton = uibutton(app.ProcessPanel, 'push');
            app.ResetmapButton.ButtonPushedFcn = createCallbackFcn(app, @ResetmapButtonPushed, true);
            app.ResetmapButton.Text = 'Reset map';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.MapButton = uibutton(app.PlotPanel, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapButton.Text = 'Map';
            app.GBsButton = uibutton(app.PlotPanel, 'push');
            app.GBsButton.ButtonPushedFcn = createCallbackFcn(app, @GrainboundariesMenuSelected, true);
            app.GBsButton.Text = 'GBs';
            app.VerticesButton = uibutton(app.PlotPanel, 'push');
            app.VerticesButton.ButtonPushedFcn = createCallbackFcn(app, @VerticesMenuSelected, true);
            app.VerticesButton.Text = 'Vertices';
            app.GrainlabelsSwitchLabel = uilabel(app.PlotPanel);
            app.GrainlabelsSwitchLabel.Text = 'Grain labels';
            app.GrainlabelsSwitch = uiswitch(app.PlotPanel, 'slider');
            app.NeighboursButton = uibutton(app.PlotPanel, 'push');
            app.NeighboursButton.ButtonPushedFcn = createCallbackFcn(app, @NeighboursButtonPushed, true);
            app.NeighboursButton.Text = 'Neighbours';
            app.ImportLog = uitextarea(app.UIFigure);
            app.ImportLog.ValueChangedFcn = createCallbackFcn(app, @ImportLogValueChanged2, true);
            app.ImportLog.Tooltip = {'Log window'};
            app.ImportLog.Value = {'==========Welcome to EBSD Polygonizer 1.0=========='; '********* Devloped by Dr Jun Liu (sam.j.liu@gmail.com)********'; ''};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBSDpolygonizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end